classdef expressPDT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExpressPDT                     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        PlateSizeDropDownLabel         matlab.ui.control.Label
        PlateSizeDropDown              matlab.ui.control.DropDown
        RowsEditFieldLabel             matlab.ui.control.Label
        RowsEditField                  matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel          matlab.ui.control.Label
        ColumnsEditField               matlab.ui.control.NumericEditField
        EnterDesiredLightDosesinJcm2Label  matlab.ui.control.Label
        CellLineEditFieldLabel         matlab.ui.control.Label
        CellLineEditField              matlab.ui.control.EditField
        PDTAgentEditFieldLabel         matlab.ui.control.Label
        PDTAgentEditField              matlab.ui.control.EditField
        PlateNameIDEditFieldLabel      matlab.ui.control.Label
        PlateNameIDEditField           matlab.ui.control.EditField
        DLIEditFieldLabel              matlab.ui.control.Label
        DLIEditField                   matlab.ui.control.EditField
        RunTab                         matlab.ui.container.Tab
        NavigationLabel                matlab.ui.control.Label
        UpButton                       matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        ResetWellButton                matlab.ui.control.Button
        ResetPlateButton               matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CalibrateTab                   matlab.ui.container.Tab
        TempCalSwitch                  matlab.ui.control.ToggleSwitch
        UpdateButton                   matlab.ui.control.Button
        ExtTempCheckBox                matlab.ui.control.CheckBox
        ExtPowerCheckBox               matlab.ui.control.CheckBox
        ExtTempEditFieldLabel          matlab.ui.control.Label
        ExtTempEditField               matlab.ui.control.NumericEditField
        ExtPowerEditFieldLabel         matlab.ui.control.Label
        ExtPowerEditField              matlab.ui.control.NumericEditField
        RelaySwitchLabel               matlab.ui.control.Label
        RelaySwitch                    matlab.ui.control.Switch
        UIAxes2                        matlab.ui.control.UIAxes
        PowermWEditFieldLabel          matlab.ui.control.Label
        PowermWEditField               matlab.ui.control.NumericEditField
        TemperatureLabel               matlab.ui.control.Label
        TemperatureGauge               matlab.ui.control.SemicircularGauge
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        MasterSwitchLabel              matlab.ui.control.Label
        MasterSwitch                   matlab.ui.control.ToggleSwitch
        PlateRunTimeEditFieldLabel     matlab.ui.control.Label
        PlateRunTimeEditField          matlab.ui.control.EditField
        DetectorAreacm2EditFieldLabel  matlab.ui.control.Label
        DetectorAreacm2EditField       matlab.ui.control.NumericEditField
        LightSourceDropDownLabel       matlab.ui.control.Label
        LightSourceDropDown            matlab.ui.control.DropDown
        ReadDAQButton                  matlab.ui.control.Button
        IntensitymWcm2EditFieldLabel   matlab.ui.control.Label
        IntensitymWcm2EditField        matlab.ui.control.NumericEditField
        LoadCalFileButton              matlab.ui.control.Button
        PowerLabel                     matlab.ui.control.Label
        PowerGauge                     matlab.ui.control.SemicircularGauge
        SupplyVoltageVEditFieldLabel   matlab.ui.control.Label
        SupplyVoltageVEditField        matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        s % DAQ session
        % TODO: Remove this
        zachData = zeros(1000, 2);
        zCount = 1;
    end
    
    properties (Access = private)
        % Well plate vars
        doses        = zeros(2, 3); % array of light doses for each well (in J/cm^2)
        times        = zeros(2, 3); % array of irradiation times for each well (in seconds)
        wellSelected = [true false false; false false false]; % Keep track of currently selected well
        wellsRun     = false(2, 3); % Keep track of wells that have been irradiated.
        wellTemps    = cell(2, 3);  % Save LED temperature during exposures
        wellPowers   = cell(2, 3);  % Save LED output powers
        tc = timer; % Initialize a timer for countdown sequence
        ts = timer; % Initialize a timer for shutter
        tl = timer; % Initialize a timer for calibration mode
        colors = struct('shortRunTime', [0.39, 0.83, 0.07], ... % Default colors
            'longRunTime',  [1.00, 0.41, 0.16], ...
            'veryLongRunTime', [1.00, 0.00, 0.00], ...
            'wellSelectedNotRun', [1.00, 0.00, 0.00], ...
            'wellNotSelectedNotRun', [1.00, 0.41, 0.16], ...
            'wellSelectedRun', [0.00 1.00 0.00], ...
            'wellNotSelectedRun', [0.39, 0.83, 0.07]);
        alphabet = {'A','B','C','D','E','F','G','H','I','J','K','L','M','N','O','P','Q','R','S','T','U','V','W','X','Y','Z'};
        totalDose = zeros(2,3);
        
        % Thermister vars
        tempCoefs = [4442, 3.34e-3;  % LED Module thermistor parameters Beta (K), R_inf (Ohms)
                     4279, 5.37e-3]; % First row, 635 nm modules; Second row, 690 nm Module
        fit            % variable to store thermister model (defined in StartupFcn
        calFile  = []; % thermistor calibration file
        tempCalInput  = []; % Temp and voltage data arrays
        powerCalInput = [];
        voltDataTemp  = [];
        voltDataPower = [];
        saveTempCalData       = false; % flag for saving temp calibration data
        savePowerCalData      = false; % flag for saving power calibration data
        saveCalDataToFileFlag = false; % flag for saving power calibration data to file
        savePlate             = false; % flag for saving wellplate setup and stats
        
        % Power vars
        % 54.65 - Volts from meter -> power reading,
        % 50.68 - Power on side -> power at plate
        powerCoefs = [52.08 61.86];
        
        % DAQ Vars
        deviceInfo % Info about daq
        DataAvailableListener % Listener triggers a callback when data is read by DAQ
        digDevCh = struct('led', 'Port2/Line0:0', 'laser', 'Port2/Line0:0') % Device channel ID for light sources
        % TODO: update port for laser once added. 
        
        modIdx = 1; % Current module in use (modIdx = 0 -> laser) 
        modules = struct('name',       {'LED-690nm-001', 'LED-635nm-002'}, ... % Name for diplay
                         'wavelength', {690,              635},            ... % LED cntr wavelength
                         'serialnum',  {001,              002},            ... % Serial number
                         'beta',       {4279,             4442},           ... % Thermister cal coeff, Beta  [Kelvin]
                         'rinf',       {5.37e-3,          3.34e-3},        ... % Thermister cal coeff, R_Inf [Ohms]
                         'range',      {180,              640},            ... % Max range set on Powermeter [mW]
                         'date',       {'2019-08-21',     '2019-08-28'});  ... % Date calibrated
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function plateStats(app)
            
            
            % Update dose table
            app.UITable.Data           = app.doses;
            app.UITable.ColumnName     = 1:app.ColumnsEditField.Value;
            app.UITable.RowName        = app.alphabet(1:app.RowsEditField.Value);
            app.UITable.ColumnEditable = true(1, app.ColumnsEditField.Value);
            
            % Corrected power
            app.IntensitymWcm2EditField.Value = app.PowermWEditField.Value / app.DetectorAreacm2EditField.Value;
            
            % Convert light dose to exposure time using power meter reading.
            app.times = app.doses * 1000 / (app.IntensitymWcm2EditField.Value);
            
            % Update total plate runtime
            app.PlateRunTimeEditField.Value = datestr(seconds(sum(sum(app.times))),'HH:MM:SS');
            if sum(sum(app.times)) < 1800 % < 30 mins
                app.PlateRunTimeEditField.BackgroundColor = app.colors.shortRunTime;
            elseif sum(sum(app.times)) > 1800 && sum(sum(app.times)) < 3600 % 30 mins - 1 hr
                app.PlateRunTimeEditField.BackgroundColor = app.colors.longRunTime;
            else  % > 1 hr
                app.PlateRunTimeEditField.BackgroundColor = app.colors.veryLongRunTime;
            end
            
        end
        
        function plateDisplay(app)
            
            cla(app.UIAxes);
            
            nRows = app.RowsEditField.Value;
            nCols = app.ColumnsEditField.Value;
            
            A1Pos = [14.3 11.2];
            wellRad = 6.35/2;
            wellStep = 9;
            xMax = A1Pos(1) * 2 + (nCols - 1) * wellStep;
            yMax = A1Pos(2) * 2 + (nRows - 1) * wellStep;
            outerDims = [0 0; xMax 0; xMax yMax; 0 yMax];
            
            %             % Font size
            %             fs = ceil( (max(nRows, nCols) / max(xMax, yMax)) * (app.ExpressPDT.Position * [0 0 0 1]') / 2);
            %             if fs < 4; fs = 4; end
            
            % Update axes properties
            app.UIAxes.XLim       = [0 xMax];
            app.UIAxes.YLim       = [0 yMax];
            app.UIAxes.XTick      = (A1Pos(1):wellStep:(A1Pos(1) + (nCols - 1) * wellStep));
            app.UIAxes.YTick      = (A1Pos(2):wellStep:(A1Pos(2) + (nRows - 1) * wellStep));
            app.UIAxes.XTickLabel = 1:nCols;
            app.UIAxes.YTickLabel = app.alphabet(1:nRows);
            app.UIAxes.YDir       = 'reverse';
            %             app.UIAxes.FontSize   = fs;
            
            % Draw plate Background
            patch(app.UIAxes, outerDims(:,1),outerDims(:,2), 'w');
            axis(app.UIAxes, 'tight');
            hold(app.UIAxes, 'on');
            
            % Create wellplate plot
            for i = 1:nRows
                for j = 1:nCols
                    
                    x = A1Pos(1) + (j - 1)*wellStep + wellRad*cos(0:0.03:2*pi);
                    y = A1Pos(2) + (i - 1)*wellStep + wellRad*sin(0:0.03:2*pi);
                    
                    if app.wellSelected(i, j) && app.wellsRun(i, j)
                        wellColor = app.colors.wellSelectedRun;
                    elseif app.wellSelected(i, j) && ~app.wellsRun(i, j)
                        wellColor = app.colors.wellSelectedNotRun;
                    elseif ~app.wellSelected(i, j) && app.wellsRun(i, j)
                        wellColor = app.colors.wellNotSelectedRun;
                    else
                        wellColor = app.colors.wellNotSelectedNotRun;
                    end
                    
                    % Draw circle
                    patch(app.UIAxes, x, y, wellColor, 'LineWidth', app.wellSelected(i, j)*2 + 1);
                    
                    % Draw time within circle
                    text(app.UIAxes, A1Pos(1) + (j-1)*wellStep, A1Pos(2) + (i-1)*wellStep, ...
                        .1, num2str(app.times(i,j), '%3.1f'),...
                        'color', [0 0 0], 'horizontalAlignment', 'center', ...
                        'fontsize', 20, 'clipping', 'on', 'visible', 'on');
                    
                end
            end
            
        end
        
        function moveSelectedWell(app, dir)
            
            [i, j] = find(app.wellSelected);
            newSelection = false(size(app.wellSelected));
            
            % Increment index according to direction
            switch dir
                case 'up'
                    newi = i - 1;
                    newj = j;
                case 'down'
                    newi = i + 1;
                    newj = j;
                case 'left'
                    newi = i;
                    newj = j - 1;
                case 'right'
                    newi = i;
                    newj = j + 1;
            end
            
            try
                % Select new position
                newSelection(newi, newj) = true;
            catch
                % If new position is out of bounds, revert to old position
                newSelection(i, j) = true;
            end
            
            % Save new position
            app.wellSelected = newSelection;
            
        end
        
        function illuminateWell(app)
            
            % Number of timer function calls
            time = app.times(app.wellSelected);
            
            % Record well as being run
            app.wellsRun(app.wellSelected) = true;
            
            if time == 0
                % Don't run if well is not up for exposure, just update plot
                plateDisplay(app);
                
            else
                
                % Update shutter/relay times
                app.ts.TasksToExecute = round(time / app.ts.Period);
                app.tc.TasksToExecute = ceil( time / app.tc.Period);
                
                % Initialize vector for saving data
                app.wellTemps{ app.wellSelected} = zeros(ceil(time), 1);
                app.wellPowers{app.wellSelected} = zeros(ceil(time), 1);
                
                % Delay countdown to sync it with relay
                app.tc.StartDelay = round(rem(time, 1), 3);
                
                % Start timers
                start(app.tc);
                start(app.ts);
            end
            
        end
        
        function toggleGuiState(app, ~, ~, state)
            % Function to enable/disable buttons  during illumination
            app.UpButton.Enable = state;
            app.DownButton.Enable = state;
            app.LeftButton.Enable = state;
            app.RightButton.Enable = state;
            app.RunButton.Enable = state;
            app.ResetPlateButton.Enable = state;
            app.ResetWellButton.Enable = state;
            app.ReadDAQButton.Enable = state;
            app.LightSourceDropDown.Enable = state;
            app.TempCalSwitch.Enable = state;
            app.ExtTempEditField.Enable = state;
            app.UpdateButton.Enable = state;
            
            % Replot data when timer is done
            if state == "On"
                plateDisplay(app);
                
                % save app variable in base workspace
                assignin('base', 'wellTemps', app.wellTemps);
                assignin('base', 'wellPowers', app.wellPowers);
            end
            
        end
        
        function timerCountdown(app, timerObj, ~)
            
            % Number of executions remaining
            timeRemaining = timerObj.TasksToExecute - timerObj.TasksExecuted;
            
            % Extra code for debugging timer
            % fprintf('%4.1f\n',timeRemaining);
            
            % Accumulate Dose
            app.totalDose(app.wellSelected) = app.totalDose(app.wellSelected) + app.IntensitymWcm2EditField.Value/1000;
            
            %             fprintf('%4.1f\n', app.totalDose);
            
            %             if app.totalDose > app.doses(app.wellSelected)
            %                 stop(app.ts);
            %                 stop(app.tc);
            %             end
            
            % Location of appropriate text obj in UIAxes.Children list
            i = length(app.UIAxes.Children) - 2*find(app.wellSelected');
            
            % Update string
            app.UIAxes.Children(i).String = sprintf('%i', timeRemaining);
            %             app.UIAxes.Children(i).String = sprintf('%i \n %3.1f', timeRemaining, app.totalDose);
            
            % Alternate color schemes during countdown
            if mod(timeRemaining, 2) == 0
                app.UIAxes.Children(i).Color       = [1 1 1]; % White
                app.UIAxes.Children(i+1).FaceColor = [0 0 1]; % Blue
            else
                app.UIAxes.Children(i).Color       = [0 0 0]; % Black
                app.UIAxes.Children(i+1).FaceColor = [0 1 1]; % Cyan
            end
            
            % Update gui display
            updateTemp(app);
            
            % Save data
            app.wellTemps{ app.wellSelected}(timerObj.TasksExecuted) = app.TemperatureGauge.Value;
            app.wellPowers{app.wellSelected}(timerObj.TasksExecuted) = app.PowerGauge.Value;
            
            % Output temp and power data to cmd window
            %             fprintf('%6.5f \t %6.5f \n', app.TemperatureGauge.Value, app.PowerGauge.Value)
            
        end
        
        function daqInit(app)
            
            % Initialize daq
            try
                app.deviceInfo = daq.getDevices;
                
            catch
                warndlg('Error: No DAQ Detected', 'No DAQ Found');
                return;
            end
            
            if isempty(app.deviceInfo)
                % No daqs found
                warndlg('Error: No DAQ Detected', 'No DAQ Found');
                app.MasterSwitch.Value = 'Off';
                return;
            end
            
            % Create session
            app.s = daq.createSession(app.deviceInfo.Vendor.ID);
            
            % Measure thermister voltage from channel 0.
            app.s.addAnalogInputChannel(app.deviceInfo.ID, 'ai0', 'Voltage');
            app.s.Channels(1,1).TerminalConfig = 'SingleEnded';
            
            % Powermeter voltage from channel 1
            app.s.addAnalogInputChannel(app.deviceInfo.ID, 'ai1', 'Voltage');
            app.s.Channels(1,2).TerminalConfig = 'SingleEnded';
            
            % Add digital channel for relay control
            if strcmp(app.LightSourceDropDown.Value, 'Laser')
                % Port for laser shutter
                devCh = app.digDevCh.laser;
            else
                % Port for LED relay
                devCh = app.digDevCh.led;
            end
            addDigitalChannel(app.s, app.deviceInfo.ID, devCh, 'OutputOnly');
            
            updateTemp(app);
            
            beep ON;
            beep;
        end
        
        function daqQuit(app)
            
            % TODO: check if timers are running and stop them. 
            
            if ~isempty(app.s)
                stop(app.s);
            end
            
            delete(app.s);
            delete(app.DataAvailableListener);
            app.s = [];
            
            % Write data to file (zach, TODO: remove)
%             writematrix(app.zachData, "cal\OpticalData_zach.txt", "Delimiter","tab");
            
        end
        
        function relayIO(app, ~, ~, value)
            
            if value % Open shutter
                
                % Turn lamp "on"
                app.StatusLamp.Color = [0 1 0];
                
                % Send a "1"
                outputSingleScan(app.s, uint32(value));
            else
                
                % Send a "0"
                outputSingleScan(app.s, uint32(value));
                
                % Turn status lamp off
                app.StatusLamp.Color = [1 0 0];
                
                % Notify
                beep;
            end
        end
        
        function doNothing(~, ~, ~)
            % Don't move a muscle...
        end
        
        function updateTempWrap(app,~,~)
            % Wrapper for the updateTemp function that catches and displays
            % errors. Since updateTemp is called in a Timer function, this 
            % is necessary for debuggging.
            try 
                updateTemp(app)
            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'on'));
                beep;
            end
            
        end
        
        function updateTemp(app)
            % Measure voltage from DAQ
            v = inputSingleScan(app.s);
            
            % Zach's Major Experiment. TODO: Remove
%             powerMeterReading = str2double(input("Power Meter Reading \n"));
%             app.zachData(app.zCount,1) = v(2);
%             app.zCount(app.zCount, 2) = powerMeterReading;
%             app.zCount = app.zcount + 1;
            
            % Subtract 2 mV DC baseline from power meter channel.
            v(2) = v(2);% - 0.002;
            
            switch app.LightSourceDropDown.Value
                case "LED - 690"
                    % Convert volts to temperature (in Fahrenheit)
                    temp = app.fit(app.tempCoefs(2, :), v(1))*9/5 + 32;
                    
                    % volts to power in mW
                    power = v(2)*app.powerCoefs(1);
                    
                case "LED - 635"
                    % Convert volts to temperature (in Fahrenheit)
                    temp = app.fit(app.tempCoefs(1, :), v(1))*9/5 + 32;
                    
                    % volts to power in mW
                    power = v(2)*app.powerCoefs(2);
%                     power = power*3.555;
            end
            
            % Update temperature in display
            app.TemperatureGauge.Value = temp;
            app.TemperatureLabel.Text = sprintf('%3.1f %cF', temp, char(176));
            
            % Update power in display
            app.PowerGauge.Value = power;
%             app.PowerLabel.Text = sprintf('%3.1f mW', power);
            app.PowerLabel.Text = sprintf("%3.2f volts", v(2));
            
%             app.PowerGauge.Value = app.fit(app.tempCoefs, v(1));
%             app.PowerLabel.Text = sprintf('%3.1f C', app.fit(app.tempCoefs, v(1)));
            
            if app.saveTempCalData
                
                app.voltDataTemp(end+1) = v(1);
                app.tempCalInput(end+1) = (app.ExtTempEditField.Value - 32)*5/9;
                
%                 fprintf('%6.5f \t %6.5f\n',v(2), app.ExtPowerEditField.Value);
                
                app.saveTempCalData = false;
                
                % Update coefs
%                 calibrateTemp(app);
            end
            
            if app.savePowerCalData
                % Save voltage reading and user input
                app.voltDataPower(end+1, 1) = v(2);
                app.powerCalInput(end+1, 1) = app.ExtPowerEditField.Value;
                
%                 % Calibrate (linear fit)
%                 if length(app.powerCalInput) > 1
%                     % With intercept
% %                     temp = [app.voltDataPower ones(length(app.voltDataPower), 1)];
% 
%                     % No intercept
%                     temp = [app.voltDataPower zeros(length(app.voltDataPower), 1)];
%                     app.powerCoefs = temp\app.powerCalInput;
%                 end
%                 
%                  fprintf('M = %5.3f \t B = %5.3f \n',app.powerCoefs(1), app.powerCoefs(2));
                
                app.savePowerCalData = false;
            end
            
        end
        
        function calibrateTemp(app)
            
            % Find coefs that minimize the error between the measurement 'temp' and the model.
            % Use the default (or current) fit params as the initial condition for fminsearch.
            app.tempCoefs = fminsearch(@(p) norm(app.tempData - app.fit(p, app.voltData)), app.tempCoefs);
            
        end
        
        function loadTempCalFile(app)
            
            %TODO make option to switch between temp and power.
            if isempty(app.calFile)
                % Load default file
                switch app.LightSourceDropDown.Value
                    case 'LED - 690'
                        app.calFile = [cd filesep 'cal' filesep 'LEDboard690cal_21AUG2019.txt'];
                    case 'LED - 635'
                        app.calFile = [cd filesep 'cal' filesep 'LEDboard635cal_28AUG2019.txt'];
                    case 'Laser'
                        % No temperature to monitor
                        return;
                end
            end
            
            try
                data = load(app.calFile);
            catch
                warndlg('Thermister Calibration file not found.', 'Error');
                return;
            end
            
            % Save data
            if ~isempty(data)
                app.voltDataPower = data(:,1);
                app.powerCalInput = data(:,2);
            end
        end


        function exitFlag = saveCalDataToFile(app)
            
            exitFlag = false;
            
            % Save temp calibration data
            answer = questdlg('Do you want to save new calibration data?', 'Save?', 'Yes', 'No', 'Cancel', 'Yes');
            if isempty(answer)
                % User closed dialogue box
                exitFlag = true;
                return;
            end
            
            % Appends new data to the current calibration file
            if answer == "Yes"
                
                [file,path] = uiputfile('*.txt');
                if ~isempty(app.voltDataPower)
                    writematrix([app.voltDataPower(:), app.powerCalInput(:)], fullfile(path,file), "Delimiter", 'tab');
                elseif ~isempty(app.voltDataTemp)
                    writematrix([app.voltDataTemp(:), app.tempCalInput(:)], fullfile(path,file), "Delimiter", 'tab');
                end
            end
            
            app.saveCalDataToFileFlag = false;
            
        end
        
        function exitFlag = savePlateDataToFile(app)
            
            exitFlag = false;
            
            if app.savePlate
                % Save data and metadata associated with plate
                answer = questdlg('Do you want to save plate data?', 'Save?', 'Yes', 'No', 'Cancel', 'Yes');
                if isempty(answer) || answer == "Cancel"
                    % User closed dialogue box
                    exitFlag = true;
                    return;
                end
                
                if answer == "Yes"
                    % Save metadata to file
                    defaultFileName = [app.CellLineEditField.Value '-' app.PDTAgentEditField.Value '-' datestr(now, 'ddmmmyyyy')];
                    
                    % Get location from user
                    [file, path] = uiputfile('*.*','Save plate data', [defaultFileName '.txt']);
                    if isequal(file,0) || isequal(path,0)
                        exitFlag = true;
                        return;
                    end
                    
                    wellTemps = app.wellTemps;
                    wellPowers = app.wellPowers;
                    % volts = app....
                    
                    fID = fopen(fullfile(path, file), 'w');
                    
                    fprintf(fID, ':: pdtControl Experiment File :: \n\n');
                    fprintf(fID, 'Plate ID:            %s \n', app.PlateNameIDEditField.Value);
                    fprintf(fID, 'Date:                %s \n', datestr(now));
                    fprintf(fID, 'Cell Line:           %s \n', app.CellLineEditField.Value);
                    fprintf(fID, 'PDT Agent:           %s \n', app.PDTAgentEditField.Value);
                    fprintf(fID, 'Drug-Light Interval: %s \n', app.DLIEditField.Value);
                    fprintf(fID, 'Light Source:        %s \n', app.LightSourceDropDown.Value);
                    fprintf(fID, 'Power:               %5.3f [mW/cm^2] \n', app.IntensitymWcm2EditField.Value);
                    fprintf(fID, 'Plate Runtime:       %s (%5.1f seconds) \n\n\n', app.PlateRunTimeEditField.Value, sum(sum(app.times)));
                    
                    fprintf(fID, 'Well # \t Well ID \t Illum. Time (s) \t Dose (J/cm^2) \t Run? \t Avg. Temp (°F) \t Adj. Dose (J/cm^2) \n');
                    fprintf(fID, '------ \t ------- \t --------------- \t ------------- \t ---- \t -------------- \t ------------------ \n');
                    k = 1;
                    % TODO: Add/change to update the "true" dose given based on timer precision
                    for i = 1:app.RowsEditField.Value
                        for j = 1:app.ColumnsEditField.Value
                            meanTemp = mean(app.wellTemps{i,j}(app.wellTemps{i,j} ~= 0));
                            fprintf(fID, ' %02d  \t  %s     \t      %5.1f      \t      %5.1f  \t   %i  \t     %4.1f \t            %4.3e \n', ...
                                k, [app.alphabet{i} num2str(j)], app.times(i, j), app.doses(i, j), app.wellsRun(i, j), meanTemp, app.totalDose(i,j));
                            
                            k = k + 1;
                        end
                    end
                    
                    fclose(fID);
                    
                    save([fullfile(path, file(1:end-4)) '.mat'], 'wellTemps', 'wellPowers');
                    
                    
                end
                
                app.savePlate = false;
                
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Load plate
            plateStats(app);
            
            % Initialize timer countdown:
            % The countdown timer controls the gui during well exposure, including animating the countdown
            % sequence and grabbing a thermister meaurement. Low precision timer with 1 second period.
            app.tc.Name           = 'countdown';
            app.tc.BusyMode       = 'queue';
            app.tc.ExecutionMode  = 'fixedRate';
            app.tc.StartDelay     = 0.0;
            app.tc.Period         = 1;
            app.tc.StartFcn       = {@app.toggleGuiState, 'Off'}; % When timer starts, disable buttons
            app.tc.TimerFcn       =  @app.timerCountdown;         % During timer execution, animate countdown
            app.tc.StopFcn        = {@app.toggleGuiState, 'On'};  % When timer stops, enable buttons
            app.tc.TasksToExecute = 1;
            
            % Initialize timer shutter
            % The shutter timer is a high precision (10 ms) timer for controlling the light source;
            % To keep consumption low, nothing computationally expensive should be associated with this timer.
            app.ts.Name           = 'shutter';
            app.ts.BusyMode       = 'queue';
            app.ts.ExecutionMode  = 'fixedRate';
            app.ts.StartDelay     = 0.0;
            app.ts.Period         = 0.01;
            app.ts.StartFcn       = {@app.relayIO, 1}; % When timer starts, open shutter
            app.ts.TimerFcn       =  @app.doNothing;   % Do nothing, (Note, TimerFcn is required, this is just a dummy function call)
            app.ts.StopFcn        = {@app.relayIO, 0}; % When timer stops, close shutter
            app.ts.TasksToExecute = 1;
            
            % Initialize timer for calibration mode
            %
            app.tl.Name           = 'cal';
            app.tl.BusyMode       = 'queue';
            app.tl.ExecutionMode  = 'fixedRate';
            app.tl.StartDelay     = 0.0;
            app.tl.Period         = 0.5;
            app.tl.StartFcn       = []; %
            app.tl.TimerFcn       = @app.updateTempWrap;   %
            app.tl.StopFcn        = []; %
            app.tl.TasksToExecute = 72000;
            
            % Save thermister temp model
            app.fit = @(p, x) p(1) ./ log((499./p(2)).*(5./x - 1)) - 273.15;
            
            % Load calibration file
%             loadTempCalFile(app);
            
            % Update calibration
%             calibrateTemp(app);
            
        end

        % Value changed function: PlateSizeDropDown
        function PlateSizeDropDownValueChanged(app, event)
            switch app.PlateSizeDropDown.Value
                
                case '6-well'
                    app.RowsEditField.Value = 2;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 3;
                    app.ColumnsEditField.Editable = false;
                    
                case '12-well'
                    app.RowsEditField.Value = 3;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 4;
                    app.ColumnsEditField.Editable = false;
                    
                case '24-well'
                    app.RowsEditField.Value = 4;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 6;
                    app.ColumnsEditField.Editable = false;
                    
                case 'Custom'
                    app.RowsEditField.Editable = true;
                    app.ColumnsEditField.Editable = true;
                    
            end
            
            % Update dose matrix
            % TODO: Fix so that it is not reset to zeros every time
            app.doses             = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.totalDose         = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellTemps         = cell( app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellPowers        = cell( app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellSelected      = logical(app.doses);
            app.wellsRun          = logical(app.doses);
            app.wellSelected(1,1) = true;
            
            % Update plate stats
            plateStats(app);
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Update dose matrix
            app.doses(event.Indices(1), event.Indices(2)) = event.NewData;
            
            % Recalculate plate stats
            plateStats(app);
            
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            % Update dose matrix
            % TODO: Fix so that it is not reset to zeros every time
            app.doses             = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.totalDose         = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellPowers        = cell( app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellTemps         = cell( app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellSelected      = logical(app.doses);
            app.wellsRun          = logical(app.doses);
            app.wellSelected(1,1) = true;
            
            % Update plate stats
            plateStats(app);
            
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            % Update dose matrix
            % TODO: Fix so that it is not reset to zeros every time
            app.doses             = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.totalDose         = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellTemps         = cell( app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellPowers        = cell( app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellSelected      = logical(app.doses);
            app.wellsRun          = logical(app.doses);
            app.wellSelected(1,1) = true;
            
            % Update plate stats
            plateStats(app);
        end

        % Value changed function: PowermWEditField
        function PowermWEditFieldValueChanged(app, event)
            % Update plate stats
            plateStats(app);
            if app.ts.Running == "off"
                plateDisplay(app)
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'Setup'
                    plateStats(app);
                    
                case 'Run'
                    plateDisplay(app);
            end
        end

        % Value changed function: DetectorAreacm2EditField
        function DetectorAreacm2EditFieldValueChanged(app, event)
            % Update plate stats
            plateStats(app);
            if app.ts.Running == "off"
                plateDisplay(app)
            end
            
        end

        % Value changed function: MasterSwitch
        function MasterSwitchValueChanged(app, event)
            switch app.MasterSwitch.Value
                case 'On'
                    daqInit(app);
                    
                case 'Off'
                    daqQuit(app);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Daq must be initialzed first
            if app.MasterSwitch.Value == "Off"
                warndlg('Turn Master Swtich on to initialize DAQ.', 'DAQ Not Initialized');
                return;
            end
            
            % Open shutter/relay and start countdown if well has not been
            % run
            if ~app.wellsRun(app.wellSelected)
                illuminateWell(app);
            end
            
            % Flag to save plate data
            app.savePlate = true;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Cancel timers
            if app.tc.Running
                stop(app.tc);
            end
            
            if app.ts.Running
                stop(app.ts);
            end
            
            % Stop daq session if running
            if app.TempCalSwitch.Value == "On"
                % Stop daq session
                if app.s.IsRunning
                    stop(app.s);
                end
                
                % Disable temp calibration components
                app.TempCalSwitch.Value = 'Off';
                app.ExtTempEditField.Enable = false;
                app.ExtTempEditFieldLabel.Enable = false;
                app.UpdateButton.Enable = false;
                
                % Enable well illumination
                app.RunButton.Enable = true;
                app.ReadDAQButton.Enable = true;
                
                % Save data
                flag = saveTempCalDataToFile(app);
                if flag
                    
                end
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            moveSelectedWell(app, 'up');
            plateDisplay(app);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            moveSelectedWell(app, 'down');
            plateDisplay(app);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            moveSelectedWell(app, 'left');
            plateDisplay(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            moveSelectedWell(app, 'right');
            plateDisplay(app);
        end

        % Button pushed function: ResetWellButton
        function ResetWellButtonPushed(app, event)
            app.wellsRun(app.wellSelected)   = false;
            app.wellTemps{app.wellSelected}  = [];
            app.wellPowers{app.wellSelected} = [];
            app.totalDose(app.wellSelected)  = 0;
            plateDisplay(app);
        end

        % Button pushed function: ResetPlateButton
        function ResetPlateButtonPushed(app, event)
            % Prompt user to save plate before resetting
            flag = savePlateDataToFile(app);
            if flag
                % Don't reset
                return;
            end
            app.wellsRun   = false(size(app.wellSelected));
            app.wellTemps  = cell( size(app.wellsRun));
            app.wellPowers = cell( size(app.wellsRun));
            app.totalDose  = zeros(size(app.wellsRun));
            plateDisplay(app);
            
        end

        % Close request function: ExpressPDT
        function ExpressPDTCloseRequest(app, event)
            
            if app.tc.Running
                stop(app.tc);
            end
            
            if app.ts.Running
                stop(app.ts);
            end
            
            if app.tl.Running
                stop(app.tl);
            end
            
            flag = savePlateDataToFile(app);
            if flag
                % Don't reset
                return;
            end
            
            daqQuit(app);
            delete(app);
        end

        % Value changed function: LightSourceDropDown
        function LightSourceDropDownValueChanged(app, event)
            
            % Reset daq session
            if app.MasterSwitch.Value == "On"
                daqQuit(app);
                daqInit(app);
                
            end
        end

        % Button pushed function: ReadDAQButton
        function ReadDAQButtonPushed(app, event)
            if app.MasterSwitch.Value == "On"
                 v = inputSingleScan(app.s);
                updateTemp(app);
                
                fprintf('%4.3f\n', v(2))
            else
                warndlg('DAQ must be initialized first.', 'Initialize DAQ');
                
            end
        end

        % Value changed function: TempCalSwitch
        function TempCalSwitchValueChanged(app, event)
            
            if app.MasterSwitch.Value == "Off"
                warndlg('DAQ must be initialized first.', 'Initialize DAQ');
                app.TempCalSwitch.Value = "Off";
                return;
            end
            
            switch app.TempCalSwitch.Value
                case 'On'
                    % Enable temp calibration components
                    app.ExtTempEditFieldLabel.Enable = true;
                    app.ExtPowerEditFieldLabel.Enable = true;
                    app.RelaySwitch.Enable = true;
                    app.UpdateButton.Enable = true;
                    
                    app.ExtTempCheckBox.Enable = true;
                    if app.ExtTempCheckBox.Value
                        app.ExtTempEditField.Enable = true;
                    end
                    
                    app.ExtPowerCheckBox.Enable = true;
                    if app.ExtPowerCheckBox.Value
                        app.ExtPowerEditField.Enable = true;
                    end
                    
                    % Disable well illumination
                    app.RunButton.Enable = false;
                    app.ReadDAQButton.Enable = false;
                    
                    % Start calibration timer
                    start(app.tl)

                case 'Off'
                    
                    % Stop daq session
                    if app.tl.Running
                        stop(app.tl);
                    end
                    
                    if app.RelaySwitch.Value == "On"
                        relayIO(app, [], [], 0);
                        app.RelaySwitch.Value = "Off";
                    end
                    
                    % Disable temp calibration components
                    app.ExtTempEditField.Enable = false;
                    app.ExtTempEditFieldLabel.Enable = false;
                    app.ExtPowerEditField.Enable = false;
                    app.ExtPowerEditFieldLabel.Enable = false;
                    app.RelaySwitch.Enable = false;
                    app.UpdateButton.Enable = false;
                    app.ExtTempCheckBox.Enable = false;
                    app.ExtPowerCheckBox.Enable = false;
                    
                    % Enable well illumination
                    app.RunButton.Enable = true;
                    app.ReadDAQButton.Enable = true;
                    
                    % Save data to file
                    
                    if app.saveCalDataToFileFlag
                        flag = saveCalDataToFile(app);
                        if flag
                            % TODO: finsh... 
                        end
                    end
                   
            end
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Grab user input
            
            if app.ExtTempCheckBox.Value
                app.saveTempCalData = true;
                app.saveCalDataToFileFlag = true;
            end
            
            if app.ExtPowerCheckBox.Value
                app.savePowerCalData = true;
                app.saveCalDataToFileFlag = true;
            end
            
        end

        % Button pushed function: LoadCalFileButton
        function LoadCalFileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select Calibration File');
            if isequal(file, 0) || isequal(path, 0)
                % User pressed cancel
                return;
            end
            
            % Save new file
            app.calFile = fullfile(path, file);
            
            % Load new file
            loadTempCalFile(app);
            
            % Recalculate model
            app.savePowerCalData = true;
            % app.saveTempCalData = true;
            updateTemp(app);
        end

        % Value changed function: RelaySwitch
        function RelaySwitchValueChanged(app, event)
            switch app.RelaySwitch.Value
                
                case "On"
                    relayIO(app, [], [], 1);
                    
                case "Off"
                    relayIO(app, [], [], 0);
                    
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savePlate = true;
            savePlateDataToFile(app);
        end

        % Value changed function: ExtTempCheckBox
        function ExtTempCheckBoxValueChanged(app, event)
            switch app.ExtTempCheckBox.Value
                case true
                    app.ExtTempEditField.Enable = true;
                case false
                    app.ExtTempEditField.Enable = false;
            end
            
        end

        % Value changed function: ExtPowerCheckBox
        function ExtPowerCheckBoxValueChanged(app, event)
           switch app.ExtPowerCheckBox.Value
                case true
                    app.ExtPowerEditField.Enable = true;
                case false
                    app.ExtPowerEditField.Enable = false;
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExpressPDT = uifigure('Visible', 'off');
            app.ExpressPDT.Name = 'ExpressPDT';
            app.ExpressPDT.CloseRequestFcn = createCallbackFcn(app, @ExpressPDTCloseRequest, true);
            app.TabGroup = uitabgroup(app.ExpressPDT);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.UITable = uitable(app.SetupTab);
            app.UITable.ColumnName = {'1'; '2'; '3'};
            app.UITable.RowName = {'A'; 'B'; 'C'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PlateSizeDropDownLabel = uilabel(app.SetupTab);
            app.PlateSizeDropDownLabel.Text = 'Plate Size';
            app.PlateSizeDropDown = uidropdown(app.SetupTab);
            app.PlateSizeDropDown.Items = {'6-well', '12-well', '24-well', 'Custom'};
            app.PlateSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlateSizeDropDownValueChanged, true);
            app.PlateSizeDropDown.Tooltip = {'Choose well-plate dimensions'};
            app.PlateSizeDropDown.Value = '6-well';
            app.RowsEditFieldLabel = uilabel(app.SetupTab);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.SetupTab, 'numeric');
            app.RowsEditField.Limits = [0 26];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Editable = 'off';
            app.RowsEditField.Value = 2;
            app.ColumnsEditFieldLabel = uilabel(app.SetupTab);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.SetupTab, 'numeric');
            app.ColumnsEditField.Limits = [0 100];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Editable = 'off';
            app.ColumnsEditField.Value = 3;
            app.EnterDesiredLightDosesinJcm2Label = uilabel(app.SetupTab);
            app.EnterDesiredLightDosesinJcm2Label.Text = 'Enter Desired Light Doses in J/cm^2';
            app.CellLineEditFieldLabel = uilabel(app.SetupTab);
            app.CellLineEditFieldLabel.Text = 'Cell Line:';
            app.CellLineEditField = uieditfield(app.SetupTab, 'text');
            app.CellLineEditField.Tooltip = {'Enter cell line in current experiment'};
            app.CellLineEditField.Value = 'CELL-LINE';
            app.PDTAgentEditFieldLabel = uilabel(app.SetupTab);
            app.PDTAgentEditFieldLabel.Text = 'PDT Agent:';
            app.PDTAgentEditField = uieditfield(app.SetupTab, 'text');
            app.PDTAgentEditField.Tooltip = {'Enter agent for PDT in current experiment'};
            app.PDTAgentEditField.Value = 'AGENT';
            app.PlateNameIDEditFieldLabel = uilabel(app.SetupTab);
            app.PlateNameIDEditFieldLabel.Text = 'Plate Name/ID';
            app.PlateNameIDEditField = uieditfield(app.SetupTab, 'text');
            app.PlateNameIDEditField.Tooltip = {'Plate name or identification tag'};
            app.PlateNameIDEditField.Value = 'Plate1';
            app.DLIEditFieldLabel = uilabel(app.SetupTab);
            app.DLIEditFieldLabel.Text = 'DLI:';
            app.DLIEditField = uieditfield(app.SetupTab, 'text');
            app.DLIEditField.Tooltip = {'Drug to Light Interval'};
            app.DLIEditField.Value = '24 hours';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.NavigationLabel = uilabel(app.RunTab);
            app.NavigationLabel.Text = 'Navigation';
            app.UpButton = uibutton(app.RunTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.RunTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.RunTab, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.RunTab, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.STOPButton = uibutton(app.RunTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ResetWellButton = uibutton(app.RunTab, 'push');
            app.ResetWellButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWellButtonPushed, true);
            app.ResetWellButton.Text = 'Reset Well';
            app.ResetPlateButton = uibutton(app.RunTab, 'push');
            app.ResetPlateButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlateButtonPushed, true);
            app.ResetPlateButton.Text = 'Reset Plate';
            app.SaveButton = uibutton(app.RunTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UIAxes = uiaxes(app.RunTab);
            title(app.UIAxes, 'Well Illumination Times (seconds)')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.TempCalSwitch = uiswitch(app.CalibrateTab, 'toggle');
            app.TempCalSwitch.ValueChangedFcn = createCallbackFcn(app, @TempCalSwitchValueChanged, true);
            app.TempCalSwitch.Tooltip = {'Toggle termperature calibration feature'};
            app.UpdateButton = uibutton(app.CalibrateTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.ExtTempCheckBox = uicheckbox(app.CalibrateTab);
            app.ExtTempCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtTempCheckBoxValueChanged, true);
            app.ExtTempCheckBox.Enable = 'off';
            app.ExtTempCheckBox.Text = '';
            app.ExtPowerCheckBox = uicheckbox(app.CalibrateTab);
            app.ExtPowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtPowerCheckBoxValueChanged, true);
            app.ExtPowerCheckBox.Enable = 'off';
            app.ExtPowerCheckBox.Text = '';
            app.ExtPowerCheckBox.Value = true;
            app.ExtTempEditFieldLabel = uilabel(app.CalibrateTab);
            app.ExtTempEditFieldLabel.Enable = 'off';
            app.ExtTempEditFieldLabel.Text = {'Ext. Temp'; ''};
            app.ExtTempEditField = uieditfield(app.CalibrateTab, 'numeric');
            app.ExtTempEditField.Limits = [0 Inf];
            app.ExtTempEditField.ValueDisplayFormat = '%.0f';
            app.ExtTempEditField.Enable = 'off';
            app.ExtTempEditField.Value = 72;
            app.ExtPowerEditFieldLabel = uilabel(app.CalibrateTab);
            app.ExtPowerEditFieldLabel.Enable = 'off';
            app.ExtPowerEditFieldLabel.Text = {'Ext. Power'; ''};
            app.ExtPowerEditField = uieditfield(app.CalibrateTab, 'numeric');
            app.ExtPowerEditField.Limits = [0 Inf];
            app.ExtPowerEditField.Enable = 'off';
            app.RelaySwitchLabel = uilabel(app.CalibrateTab);
            app.RelaySwitchLabel.Enable = 'off';
            app.RelaySwitchLabel.Text = 'Relay';
            app.RelaySwitch = uiswitch(app.CalibrateTab, 'slider');
            app.RelaySwitch.ValueChangedFcn = createCallbackFcn(app, @RelaySwitchValueChanged, true);
            app.RelaySwitch.Enable = 'off';
            app.UIAxes2 = uiaxes(app.CalibrateTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PowermWEditFieldLabel = uilabel(app.ExpressPDT);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.ExpressPDT, 'numeric');
            app.PowermWEditField.Limits = [0 1000];
            app.PowermWEditField.ValueChangedFcn = createCallbackFcn(app, @PowermWEditFieldValueChanged, true);
            app.PowermWEditField.Tooltip = {'Power as measured by external power meter'};
            app.PowermWEditField.Value = 50;
            app.TemperatureLabel = uilabel(app.ExpressPDT);
            app.TemperatureLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.ExpressPDT, 'semicircular');
            app.TemperatureGauge.Limits = [60 180];
            app.TemperatureGauge.MajorTicks = [60 90 120 150 180];
            app.TemperatureGauge.Value = 72;
            app.StatusLampLabel = uilabel(app.ExpressPDT);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ExpressPDT);
            app.StatusLamp.Tooltip = {'Shutter IO state'};
            app.MasterSwitchLabel = uilabel(app.ExpressPDT);
            app.MasterSwitchLabel.Text = 'Master Switch';
            app.MasterSwitch = uiswitch(app.ExpressPDT, 'toggle');
            app.MasterSwitch.ValueChangedFcn = createCallbackFcn(app, @MasterSwitchValueChanged, true);
            app.MasterSwitch.Tooltip = {'Initialize DAQ'};
            app.PlateRunTimeEditFieldLabel = uilabel(app.ExpressPDT);
            app.PlateRunTimeEditFieldLabel.Text = 'Plate Run Time';
            app.PlateRunTimeEditField = uieditfield(app.ExpressPDT, 'text');
            app.PlateRunTimeEditField.Editable = 'off';
            app.PlateRunTimeEditField.Tooltip = {'Total plate illumination time.'};
            app.PlateRunTimeEditField.Value = '00:00:00';
            app.DetectorAreacm2EditFieldLabel = uilabel(app.ExpressPDT);
            app.DetectorAreacm2EditFieldLabel.Text = 'Detector Area (cm^2)';
            app.DetectorAreacm2EditField = uieditfield(app.ExpressPDT, 'numeric');
            app.DetectorAreacm2EditField.Limits = [0 100];
            app.DetectorAreacm2EditField.ValueChangedFcn = createCallbackFcn(app, @DetectorAreacm2EditFieldValueChanged, true);
            app.DetectorAreacm2EditField.Tooltip = {'Factor to conver power meter reading to mW/cm^2 (If no factor'; ' enter 1).'};
            app.DetectorAreacm2EditField.Value = 0.71;
            app.LightSourceDropDownLabel = uilabel(app.ExpressPDT);
            app.LightSourceDropDownLabel.Text = 'Light Source';
            app.LightSourceDropDown = uidropdown(app.ExpressPDT);
            app.LightSourceDropDown.Items = {'LED - 690', 'LED - 635', 'Laser'};
            app.LightSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @LightSourceDropDownValueChanged, true);
            app.LightSourceDropDown.Tooltip = {'Choose light source'};
            app.LightSourceDropDown.Value = 'LED - 690';
            app.ReadDAQButton = uibutton(app.ExpressPDT, 'push');
            app.ReadDAQButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDAQButtonPushed, true);
            app.ReadDAQButton.Tooltip = {'Read temperature once'};
            app.ReadDAQButton.Text = 'Read DAQ';
            app.IntensitymWcm2EditFieldLabel = uilabel(app.ExpressPDT);
            app.IntensitymWcm2EditFieldLabel.Text = 'Intensity (mW/cm^2)';
            app.IntensitymWcm2EditField = uieditfield(app.ExpressPDT, 'numeric');
            app.IntensitymWcm2EditField.Limits = [0 Inf];
            app.IntensitymWcm2EditField.ValueDisplayFormat = '%4.1f';
            app.IntensitymWcm2EditField.Editable = 'off';
            app.IntensitymWcm2EditField.Tooltip = {''};
            app.IntensitymWcm2EditField.Value = 70.4;
            app.LoadCalFileButton = uibutton(app.ExpressPDT, 'push');
            app.LoadCalFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalFileButtonPushed, true);
            app.LoadCalFileButton.Tooltip = {'Load thermister calibration file'};
            app.LoadCalFileButton.Text = 'Load Cal. File';
            app.PowerLabel = uilabel(app.ExpressPDT);
            app.PowerLabel.Text = 'Power';
            app.PowerGauge = uigauge(app.ExpressPDT, 'semicircular');
            app.PowerGauge.Limits = [0 120];
            app.PowerGauge.MajorTicks = [0 30 60 90 120];
            app.SupplyVoltageVEditFieldLabel = uilabel(app.ExpressPDT);
            app.SupplyVoltageVEditFieldLabel.Text = 'Supply Voltage (V)';
            app.SupplyVoltageVEditField = uieditfield(app.ExpressPDT, 'numeric');
            app.SupplyVoltageVEditField.Limits = [0 1000];
            app.SupplyVoltageVEditField.Tooltip = {'Power as measured by external power meter'};
            app.SupplyVoltageVEditField.Value = 25;
            % Show the figure after all components are created
            app.ExpressPDT.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expressPDT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExpressPDT)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExpressPDT)
        end
    end
end