classdef pdtControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        PlateSizeDropDownLabel         matlab.ui.control.Label
        PlateSizeDropDown              matlab.ui.control.DropDown
        RowsEditFieldLabel             matlab.ui.control.Label
        RowsEditField                  matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel          matlab.ui.control.Label
        ColumnsEditField               matlab.ui.control.NumericEditField
        EnterDesiredLightDosesinJcm2Label  matlab.ui.control.Label
        RunTab                         matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        NavigationLabel                matlab.ui.control.Label
        UpButton                       matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        ResetWellButton                matlab.ui.control.Button
        ResetPlateButton               matlab.ui.control.Button
        FluencemWcm2EditFieldLabel     matlab.ui.control.Label
        FluencemWcm2EditField          matlab.ui.control.NumericEditField
        LEDTemperatureGaugeLabel       matlab.ui.control.Label
        LEDTemperatureGauge            matlab.ui.control.SemicircularGauge
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        MasterSwitchLabel              matlab.ui.control.Label
        MasterSwitch                   matlab.ui.control.ToggleSwitch
        PlateRunTimeEditFieldLabel     matlab.ui.control.Label
        PlateRunTimeEditField          matlab.ui.control.EditField
        PowerMeterConvFactorEditFieldLabel  matlab.ui.control.Label
        PowerMeterConvFactorEditField  matlab.ui.control.NumericEditField
        LightSourceButtonGroup         matlab.ui.container.ButtonGroup
        LEDButton                      matlab.ui.control.RadioButton
        LaserButton                    matlab.ui.control.RadioButton
    end

    properties (Access = public)
        s % DAQ session
    end
    
    properties (Access = private)
        % Well plate vars
        doses = zeros(2, 3); % array of light doses for each well (in J/cm^2)
        times = zeros(2, 3); % array of irradiation times for each well (in seconds)
        wellSelected = [true false false; false false false]; % Keep track of currently selected well
        wellsRun = false(2, 3); % Keep track of wells that have been irradiated. 
        t = timer; % Initialize a timer for countdown
        colors = struct('shortRunTime', [0.39, 0.83, 0.07], ... % Default colors
                        'longRunTime',  [1.00, 0.41, 0.16], ...
                        'veryLongRunTime', [1.00, 0.00, 0.00], ...
                        'wellSelectedNotRun', [1.00, 0.00, 0.00], ...
                        'wellNotSelectedNotRun', [1.00, 0.41, 0.16], ...
                        'wellSelectedRun', [0.00 1.00 0.00], ...
                        'wellNotSelectedRun', [0.39, 0.83, 0.07]);
        
        alphabet = {'A','B','C','D','E','F','G','H','I','J','K','L','M','N','O','P','Q','R','S','T','U','V','W','X','Y','Z'};
        
        % Calibration coefficients
        tempCoefs = struct('a0', 50.76, ...
                           'a1', 117.0, ...
                           'a2', -26.21);
        volts2temp % function handle for calibration curve
        
        % DAQ Vars
        deviceInfo % Info about daq
        DataAvailableListener % Listener triggers a callback when data is read by DAQ
        
    end
    
    methods (Access = public)
    
    end
    
    methods (Access = private)
        
        function plateStats(app)
            
            % Update dose table
            app.UITable.Data           = app.doses;
            app.UITable.ColumnName     = 1:app.ColumnsEditField.Value;
            app.UITable.RowName        = app.alphabet(1:app.RowsEditField.Value);
            app.UITable.ColumnEditable = true(1, app.ColumnsEditField.Value);
            
            % Convert light dose to exposure time using power meter reading.
            app.times = app.doses * 1000 / (app.FluencemWcm2EditField.Value * app.PowerMeterConvFactorEditField.Value);
            
            % Update total plate runtime
            app.PlateRunTimeEditField.Value = datestr(seconds(sum(sum(app.times))),'HH:MM:SS');
            if sum(sum(app.times)) < 1800 % < 30 mins
                app.PlateRunTimeEditField.BackgroundColor = app.colors.shortRunTime;
            elseif sum(sum(app.times)) > 1800 && sum(sum(app.times)) < 3600 % 30 mins - 1 hr
                app.PlateRunTimeEditField.BackgroundColor = app.colors.longRunTime;
            else  % > 1 hr
                app.PlateRunTimeEditField.BackgroundColor = app.colors.veryLongRunTime;
            end
            
        end
        
        function plateDisplay(app)
            
            cla(app.UIAxes);
            
            nRows = app.RowsEditField.Value;
            nCols = app.ColumnsEditField.Value;
            
            A1Pos = [14.3 11.2];
            wellRad = 6.35/2;
            wellStep = 9;
            xMax = A1Pos(1) * 2 + (nCols - 1) * wellStep;
            yMax = A1Pos(2) * 2 + (nRows - 1) * wellStep;
            outerDims = [0 0; xMax 0; xMax yMax; 0 yMax];
            
            % Font size
            fs = ceil( (max(nRows, nCols) / max(xMax, yMax)) * (app.UIFigure.Position * [0 0 0 1]') / 2);
            if fs < 4; fs = 4; end
            
            % Update axes properties
            app.UIAxes.XLim       = [0 xMax];
            app.UIAxes.YLim       = [0 yMax];
            app.UIAxes.XTick      = (A1Pos(1):wellStep:(A1Pos(1) + (nCols - 1) * wellStep));
            app.UIAxes.YTick      = (A1Pos(2):wellStep:(A1Pos(2) + (nRows - 1) * wellStep));
            app.UIAxes.XTickLabel = 1:nCols; 
            app.UIAxes.YTickLabel = app.alphabet(1:nRows);
            app.UIAxes.YDir       = 'reverse';
            app.UIAxes.FontSize   = fs;
            
            % Draw plate Background
            patch(app.UIAxes, outerDims(:,1),outerDims(:,2), 'w');
            axis(app.UIAxes, 'tight');
            hold(app.UIAxes, 'on');
                    
            % Create wellplate plot
            for i = 1:nRows
                for j = 1:nCols
                    
                    x = A1Pos(1) + (j - 1)*wellStep + wellRad*cos(0:0.03:2*pi);
                    y = A1Pos(2) + (i - 1)*wellStep + wellRad*sin(0:0.03:2*pi);
                    
                    if app.wellSelected(i, j) && app.wellsRun(i, j)
                        wellColor = app.colors.wellSelectedRun;
                    elseif app.wellSelected(i, j) && ~app.wellsRun(i, j)
                        wellColor = app.colors.wellSelectedNotRun;
                    elseif ~app.wellSelected(i, j) && app.wellsRun(i, j)
                        wellColor = app.colors.wellNotSelectedRun;
                    else
                        wellColor = app.colors.wellNotSelectedNotRun;
                    end
                    
                    % Draw circle
                    patch(app.UIAxes, x, y, wellColor, 'LineWidth', app.wellSelected(i, j)*2 + 1);
                    
                    % Draw time within circle
                    text(app.UIAxes, A1Pos(1) + (j-1)*wellStep, A1Pos(2) + (i-1)*wellStep, ...
                                            .1, num2str(app.times(i,j), '%3.1f'),...
                                            'color', [0 0 0], 'horizontalAlignment', 'center', ...
                                            'fontsize',fs,'clipping','on','visible','on');
                    
                end
            end
            
        end
    
        function moveSelectedWell(app, dir)
            
            [i, j] = find(app.wellSelected);
            newSelection = false(size(app.wellSelected));
            
            % Increment index according to direction
            switch dir
                case 'up'
                    newi = i - 1;
                    newj = j;
                case 'down'
                    newi = i + 1;
                    newj = j;
                case 'left'
                    newi = i;
                    newj = j - 1;
                case 'right'
                    newi = i;
                    newj = j + 1;
            end
            
            try
                % Select new position
                newSelection(newi, newj) = true;
            catch
                % If new position is out of bounds, revert to old position
                newSelection(i, j) = true;
            end
            
            % Save new position
            app.wellSelected = newSelection;
            
        end
    
        function illuminateWell(app)
            
            % Start DAQ session in background
            
            % Listen for when session is done
            
            % Number of timer function calls
            nIter = ceil(app.times(app.wellSelected));
            
            % Timer must run at least once
            if nIter == 0; nIter = 1; end
            
            % Update countdown
            app.t.TasksToExecute = nIter;
            
            % Start timeer
            start(app.t);
            
            % Record well as being run
            app.wellsRun(app.wellSelected) = true;
            
        end
        
        function toggleGuiState(app, ~, ~, state)
            % Function to enable/disable buttons  during illumination
            app.UpButton.Enable = state;
            app.DownButton.Enable = state;
            app.LeftButton.Enable = state;
            app.RightButton.Enable = state;
            app.RunButton.Enable = state;
            app.FluencemWcm2EditField.Enable = state;
            app.PowerMeterConvFactorEditField.Enable = state;
            app.LEDButton.Enable = state;
            app.LaserButton.Enable = state;
            
            % Replot data when timer is done
            if strcmp(state, 'on')
                plateDisplay(app);
            end
            
        end
        
        function timerCountdown(app, timerObj, ~)
            
            % Number of executions remaining
            timeRemaining = timerObj.TasksToExecute - timerObj.TasksExecuted;
            
            % Extra code for debugging timer
            % fprintf('%4.1f\n',timeRemaining);
            
            % Location of appropriate text obj in UIAxes.Children list
            i = length(app.UIAxes.Children) - 2*find(app.wellSelected');
            
            % Update string
            app.UIAxes.Children(i).String = sprintf('%i', timeRemaining);
            
            % Alternate color schemes during countdown
            if mod(timeRemaining, 2) == 0
                app.UIAxes.Children(i).Color       = [1 1 1];
                app.UIAxes.Children(i+1).FaceColor = [0 0 1];
            else
                app.UIAxes.Children(i).Color       = [0 0 0];
                app.UIAxes.Children(i+1).FaceColor = [0 1 1];
            end
        end
        
        function daqInit(app)
            
            % Initialize daq
            try
                app.deviceInfo = daq.getDevices;
                
            catch
                warndlg('Error: No DAQ Detected', 'No DAQ Found');
            end    
            
            
            % Start Temperature monitor
            app.s = daq.createSession(app.deviceInfo.Vendor.ID);
            
            % Measure voltage from channel 0.
            app.s.addAnalogInputChannel(app.deviceInfo.ID, 'ai0', 'Voltage');
            
            % Set acquisition rate (keep low for low memory strain)
            app.s.Rate = 5;
            app.s.DurationInSeconds = 2;
            
            % Add listener
            app.DataAvailableListener = addlistener(app.s, 'DataAvailable', @(src, event) dataAvailableCallback(app, src, event));
            
            startBackground(app.s);
            
            
        end
    
        function daqQuit(app)
            
            stop(app.s);
            delete(app.s);
            delete(app.DataAvailableListener);
            app.s = [];
            
            
        end
        
        function dataAvailableCallback(app, ~, event)
            v = event.Data;
            
            % Convert volts to temperature
            temp = app.tempCoefs.a2.*v.^2 + app.tempCoefs.a1.*v + app.tempCoefs.a0;
            
            % Update temperature in display
            app.LEDTemperatureGauge.Value = temp;
            app.LEDTemperatureGaugeLabel.Text = num2str(temp, '%3.1f');
            
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            plateStats(app);
            
            % Initialize timer countdown
            app.t.BusyMode       = 'queue';
            app.t.ExecutionMode  = 'fixedRate';
            app.t.StartDelay     = 0.0;
            app.t.Period         = 1.0;
            app.t.StartFcn       = {@app.toggleGuiState, 'off'}; % When timer starts, disable buttons
            app.t.TimerFcn       =  @app.timerCountdown;         % During timer execution, animate countdown
            app.t.StopFcn        = {@app.toggleGuiState, 'on'};  % When timer stops, enable buttons
            app.t.TasksToExecute = 1;
            
            % Voltage to temperature calibration curve
%             app.volts2temp = @(t) app.tempCoefs.a2.*t.^2 + app.tempCoefs.a1.*t + app.tempCoefs.a0;
        end

        % Value changed function: PlateSizeDropDown
        function PlateSizeDropDownValueChanged(app, event)
            switch app.PlateSizeDropDown.Value
                
                case '6-well'
                    app.RowsEditField.Value = 2;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 3;
                    app.ColumnsEditField.Editable = false;
                    
                case '12-well'
                    app.RowsEditField.Value = 3;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 4;
                    app.ColumnsEditField.Editable = false;
                    
                case '24-well'
                    app.RowsEditField.Value = 4;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 6;
                    app.ColumnsEditField.Editable = false;
                    
                case '96-well'
                    app.RowsEditField.Value = 8;
                    app.RowsEditField.Editable = false;
                    
                    app.ColumnsEditField.Value = 12;
                    app.ColumnsEditField.Editable = false;
                    
                case 'Custom'
                    app.RowsEditField.Editable = true;
                    app.ColumnsEditField.Editable = true;
                    
            end
            
            % Update dose matrix
            % TODO: Fix so that it is not reset to zeros every time
            app.doses = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.wellSelected = logical(app.doses);
            app.wellsRun     = logical(app.doses);
            app.wellSelected(1,1) = true;
            
            % Reset dose matrix
            app.UITable.Data = app.doses;
            
            % Update plate stats
            plateStats(app);
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Update dose matrix
            app.doses(event.Indices(1), event.Indices(2)) = event.NewData;
            
            % Recalculate plate stats
            plateStats(app);
            
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            % Update dose matrix
            % TODO: Fix so that it is not reset to zeros every time
            app.doses = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            
            % Reset dose matrix
            app.UITable.Data = app.doses;
            
            % Update plate stats
            plateStats(app);
            
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            % Update dose matrix
            % TODO: Fix so that it is not reset to zeros every time
            app.doses = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            
            % Reset dose matrix
            app.UITable.Data = app.doses;
            
            % Update plate stats
            plateStats(app);
        end

        % Value changed function: FluencemWcm2EditField
        function FluencemWcm2EditFieldValueChanged(app, event)
            % Update plate stats
            plateStats(app);
            plateDisplay(app);
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'Setup'
                    plateStats(app);
                    
                case 'Run'
                    plateDisplay(app); 
            end
        end

        % Value changed function: PowerMeterConvFactorEditField
        function PowerMeterConvFactorEditFieldValueChanged(app, event)
            
            plateStats(app);
            plateDisplay(app);
            
        end

        % Value changed function: MasterSwitch
        function MasterSwitchValueChanged(app, event)
            switch app.MasterSwitch.Value
                case 'On'
                    daqInit(app);
                    
                case 'Off'
                    
                    daqQuit(app);
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Daq must be initialzed first
            if strcmp(app.MasterSwitch.Value, 'Off')
                warndlg('Turn Master Swtich on to initialize DAQ.', 'DAQ Not Initialized');
                
                return;
            end
            
            % Open shutter/relay and start countdown
            illuminateWell(app);
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Cancel running daq session
            
            % Cancel timer
            if app.t.Running
                stop(app.t);
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            moveSelectedWell(app, 'up');
            plateDisplay(app);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            moveSelectedWell(app, 'down');
            plateDisplay(app);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            moveSelectedWell(app, 'left');
            plateDisplay(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            moveSelectedWell(app, 'right');
            plateDisplay(app);
        end

        % Button pushed function: ResetWellButton
        function ResetWellButtonPushed(app, event)
            app.wellsRun(app.wellSelected) = false;
            plateDisplay(app);
        end

        % Button pushed function: ResetPlateButton
        function ResetPlateButtonPushed(app, event)
            app.wellsRun = false(size(app.wellSelected));
            plateDisplay(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.UITable = uitable(app.SetupTab);
            app.UITable.ColumnName = {'1'; '2'; '3'};
            app.UITable.RowName = {'A'; 'B'; 'C'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PlateSizeDropDownLabel = uilabel(app.SetupTab);
            app.PlateSizeDropDownLabel.Text = 'Plate Size';
            app.PlateSizeDropDown = uidropdown(app.SetupTab);
            app.PlateSizeDropDown.Items = {'6-well', '12-well', '24-well', '96-well', 'Custom'};
            app.PlateSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlateSizeDropDownValueChanged, true);
            app.PlateSizeDropDown.Value = '6-well';
            app.RowsEditFieldLabel = uilabel(app.SetupTab);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.SetupTab, 'numeric');
            app.RowsEditField.Limits = [0 26];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Editable = 'off';
            app.RowsEditField.Value = 2;
            app.ColumnsEditFieldLabel = uilabel(app.SetupTab);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.SetupTab, 'numeric');
            app.ColumnsEditField.Limits = [0 100];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Editable = 'off';
            app.ColumnsEditField.Value = 3;
            app.EnterDesiredLightDosesinJcm2Label = uilabel(app.SetupTab);
            app.EnterDesiredLightDosesinJcm2Label.Text = 'Enter Desired Light Doses in J/cm^2';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.UIAxes = uiaxes(app.RunTab);
            title(app.UIAxes, 'Well Illumnation Times (seconds)')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.NavigationLabel = uilabel(app.RunTab);
            app.NavigationLabel.Text = 'Navigation';
            app.UpButton = uibutton(app.RunTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.RunTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.RunTab, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.RunTab, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.STOPButton = uibutton(app.RunTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ResetWellButton = uibutton(app.RunTab, 'push');
            app.ResetWellButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWellButtonPushed, true);
            app.ResetWellButton.Text = 'Reset Well';
            app.ResetPlateButton = uibutton(app.RunTab, 'push');
            app.ResetPlateButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlateButtonPushed, true);
            app.ResetPlateButton.Text = 'Reset Plate';
            app.FluencemWcm2EditFieldLabel = uilabel(app.UIFigure);
            app.FluencemWcm2EditFieldLabel.Text = 'Fluence (mW/cm^2)';
            app.FluencemWcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FluencemWcm2EditField.Limits = [0 1000];
            app.FluencemWcm2EditField.ValueChangedFcn = createCallbackFcn(app, @FluencemWcm2EditFieldValueChanged, true);
            app.FluencemWcm2EditField.Value = 50;
            app.LEDTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.LEDTemperatureGaugeLabel.Text = 'LED Temperature';
            app.LEDTemperatureGauge = uigauge(app.UIFigure, 'semicircular');
            app.LEDTemperatureGauge.Limits = [60 260];
            app.LEDTemperatureGauge.MajorTicks = [60 100 150 200 260];
            app.LEDTemperatureGauge.Value = 72;
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.MasterSwitchLabel = uilabel(app.UIFigure);
            app.MasterSwitchLabel.Text = 'Master Switch';
            app.MasterSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MasterSwitch.ValueChangedFcn = createCallbackFcn(app, @MasterSwitchValueChanged, true);
            app.PlateRunTimeEditFieldLabel = uilabel(app.UIFigure);
            app.PlateRunTimeEditFieldLabel.Text = 'Plate Run Time';
            app.PlateRunTimeEditField = uieditfield(app.UIFigure, 'text');
            app.PlateRunTimeEditField.Editable = 'off';
            app.PlateRunTimeEditField.Value = '00:00:00';
            app.PowerMeterConvFactorEditFieldLabel = uilabel(app.UIFigure);
            app.PowerMeterConvFactorEditFieldLabel.Text = 'Power Meter Conv. Factor';
            app.PowerMeterConvFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerMeterConvFactorEditField.Limits = [0 100];
            app.PowerMeterConvFactorEditField.ValueChangedFcn = createCallbackFcn(app, @PowerMeterConvFactorEditFieldValueChanged, true);
            app.PowerMeterConvFactorEditField.Value = 0.71;
            app.LightSourceButtonGroup = uibuttongroup(app.UIFigure);
            app.LightSourceButtonGroup.Title = 'Light Source';
            app.LEDButton = uiradiobutton(app.LightSourceButtonGroup);
            app.LEDButton.Text = 'LED';
            app.LEDButton.Value = true;
            app.LaserButton = uiradiobutton(app.LightSourceButtonGroup);
            app.LaserButton.Text = 'Laser';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pdtControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end